classdef labelMaster < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LabelerUIFigure     matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        LeftPanelGrid       matlab.ui.container.GridLayout
        TabGroup            matlab.ui.container.TabGroup
        ReadDataTab         matlab.ui.container.Tab
        GridLayout2         matlab.ui.container.GridLayout
        DataDropDown        matlab.ui.control.DropDown
        LabelsDropDown      matlab.ui.control.DropDown
        RefreshButton       matlab.ui.control.Button
        SaveLoadTab         matlab.ui.container.Tab
        GridLayout6         matlab.ui.container.GridLayout
        ImportDropDown      matlab.ui.control.DropDown
        ImportButton        matlab.ui.control.Button
        OffsetButton        matlab.ui.control.Button
        OffsetTypeDropDown  matlab.ui.control.DropDown
        SaveButton          matlab.ui.control.Button
        MiscFunctionsTab    matlab.ui.container.Tab
        GridLayout5         matlab.ui.container.GridLayout
        PlayButton          matlab.ui.control.Button
        GridLayout7         matlab.ui.container.GridLayout
        SelectAllButton     matlab.ui.control.Button
        UnSelectAllButton   matlab.ui.control.Button
        TemplateModeButton  matlab.ui.control.Button
        PointsTable         matlab.ui.control.Table
        Buttons             matlab.ui.container.GridLayout
        GridLayout3         matlab.ui.container.GridLayout
        CursorSizeSlider    matlab.ui.control.Slider
        GridLayout4         matlab.ui.container.GridLayout
        DeleteButton        matlab.ui.control.Button
        EditButton          matlab.ui.control.Button
        CopyButton          matlab.ui.control.Button
        RightPanel          matlab.ui.container.Panel
        labelingGrid        matlab.ui.container.GridLayout
        MainPlot            matlab.ui.control.UIAxes
        SpectoPlot          matlab.ui.control.UIAxes
        PreviewPlot         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % Constants
        numPreviewPlotDataPoints = 1e6;
        
        % main values
        data
        labels
        
        % preview plot attributes
        previewPlotSkippingStep % Description
        previewLength
        previewPlotHandle
        previewLeftLimit
        previewRightLimit
        
        % main plot attributes
        mainPlotStart
        mainPlotEnd
        mainPlotHandle
        
        % miscellaneous and methods specific
        player
        copiedTemplatePoints % copyTemplatePoints
        
    end
    
    properties (Access = public)
        mainPlotClickResponseFunction = @addToPointsTable;
    end
    
    methods (Access = public)
        
        function [workspaceTypeVars, workspaceVars] = readWorkspaceVars(~, type)
            workspaceTypeVars =cell(0);
            
            vars = evalin('base', 'whos');
            workspaceVars = cell(size(vars));
            j = 1;
            for i=1:length(vars)
                workspaceVars{i} = vars(i).name;
                if 2  <= nargin && vars(i).class == type
                    workspaceTypeVars{j} = vars(i).name;
                    j = j+1;
                end
            end
        end
        
        function initializeEnviroment(app)
            [workspaceCatVars, workspaceVars] = app.readWorkspaceVars("categorical");
            app.DataDropDown.Items = [ { '<Select The Var>' }; workspaceVars ];
            app.LabelsDropDown.Items = [ { '<Select Cat Var>' }, workspaceCatVars ];
            workspaceTableVars = app.readWorkspaceVars("table");
            app.ImportDropDown.Items = [ { '<Select Table Var>' }, workspaceTableVars ];
            
            app.PointsTable.Data = table();
        end
        
        function mainPlotDrawer(app)
            app.mainPlotDrawerSignal;
            app.mainPlotDrawerFreq;
            app.mainPlotDrawerLabels;
            
            assignin('base', 'points', app.PointsTable.Data); % export variable to the workspace
        end
        
        function mainPlotDrawerSignal(app)
            left = 1 + (app.previewLeftLimit-1) * app.previewPlotSkippingStep;
            right = 1 + (app.previewRightLimit-1) * app.previewPlotSkippingStep;
            left = floor(left);
            right = floor(right);
            app.mainPlotStart = left;
            app.mainPlotEnd = right;
            app.mainPlotHandle = plot(app.MainPlot, app.data(left:right));
            xlim(app.MainPlot, [0, right-left]);
            
            app.mainPlotHandle.ButtonDownFcn = @app.clickOnMainPlot;
        end
        
        function mainPlotDrawerLabels(app)
            if ~isempty(app.PointsTable.Data)
                allPoints = app.PointsTable.Data.Point;
                allLabels = string(app.PointsTable.Data.Label);
                allSelected = app.PointsTable.Data.S;
                isDraw = (allPoints < app.mainPlotEnd) & ...
                    (app.mainPlotStart < allPoints);
                toDrawPoints = allPoints(isDraw);
                toNoteLabel = allLabels(isDraw);
                toNoteSelected = allSelected(isDraw);
                
                for i = 1:length(toDrawPoints)
                    if toNoteSelected(i)
                        xline(app.MainPlot, toDrawPoints(i) - app.mainPlotStart...
                            ,'-',toNoteLabel(i), 'LabelHorizontalAlignment', 'left',...
                            'LineWidth', 2);
                    else
                        xline(app.MainPlot, toDrawPoints(i) - app.mainPlotStart...
                            ,'-',toNoteLabel(i), 'LabelHorizontalAlignment', 'left', ...
                            'LineWidth', .5);
                    end
                    
                end
            end
        end
        
        function mainPlotDrawerFreq(app)
            data = app.data(app.mainPlotStart:app.mainPlotEnd);
            windowLength = 1000;
            overlap = round(0.75 * windowLength);
            ffTLength = windowLength;
            
            imgOut = stft(data,'Window', hamming(windowLength,"periodic"), ...
                'OverlapLength', overlap, 'FFTLength',ffTLength);
            
            numFeatures = windowLength/2 + 1;
            imgOut = abs(imgOut(numFeatures-1:end,:));
            imgOut = app.spectogramOutlierSmoother(imgOut);
            imgOut = 1./imgOut';
            imgOut = db(imgOut);
            
            mainPlotDim = app.MainPlot.InnerPosition;
            spctoPlotDim = app.SpectoPlot.InnerPosition;
            spectoPlotHandle = imshow(imgOut','Parent', app.SpectoPlot, ...
                'XData', [1 spctoPlotDim(3)], ...
                'YData', [1 spctoPlotDim(4)]);
            app.SpectoPlot.XLim = [0 spectoPlotHandle.XData(2)];
            app.SpectoPlot.YLim = [0 spectoPlotHandle.YData(2)];
        end
    end
    
    methods (Access = private)
        
        function previewPlotDraw(app)
            app.previewPlotSkippingStep = ceil(length(app.data)...
                /app.numPreviewPlotDataPoints);
            app.previewPlotHandle = plot(app.PreviewPlot,...
                app.data(1:app.previewPlotSkippingStep:end));
            app.previewLength = ...
                length(app.data(1:app.previewPlotSkippingStep:end));
            app.previewPlotHandle.ButtonDownFcn = @app.clickOnPreviewPlot;
        end
        
        function clickOnPreviewPlot(app, ~, ~)
            cp = get(app.PreviewPlot,'CurrentPoint');
            
            app.previewPlotDraw;
            
            windowSize = app.previewLength * app.CursorSizeSlider.Value / 100;
            app.previewLeftLimit = floor(cp(1,1) - windowSize/2);
            app.previewLeftLimit = max(1, app.previewLeftLimit);
            app.previewRightLimit = app.previewLeftLimit + windowSize;
            app.previewRightLimit = min(app.previewRightLimit, app.previewLength);
            
            xline(app.PreviewPlot, app.previewLeftLimit, '--red');
            xline(app.PreviewPlot, app.previewRightLimit, '--red');
            
            app.mainPlotDrawer;
        end
        
        function clickOnMainPlot(app, ~, ~)
            cp = get(app.MainPlot, 'CurrentPoint');
            thisPoint = app.mainPlotStart + cp(1,1);
            
            app.mainPlotClickResponseFunction(app, thisPoint);
            
            app.mainPlotDrawer;
        end
        
        function addToPointsTable(app, thisPoints, thisLabel, thisSelected)
            for i = 1:numel(thisPoints)
                Label = categorical({''}, cellstr(app.labels));
                Point = thisPoints(i);
                S = false;
                thisTable = table(Label, Point, S);
                app.PointsTable.Data = [app.PointsTable.Data; thisTable];
                
                if 4 <= nargin
                    app.PointsTable.Data.S(end) = thisSelected(i);
                else
                    app.PointsTable.Data.S(end) = true;
                end
                app.PointsTable.Data.S(1:end-1) = ...
                    false(height(app.PointsTable.Data)-1,1);
                
                if 3 <= nargin
                    app.PointsTable.Data.Label(end) = thisLabel(i);
                end
                
                app.PointsTable.Data = sortrows(app.PointsTable.Data, 'Point');
                app.PointsTable.Data.Row = string(1:height(app.PointsTable.Data));
            end
        end
        
        function editPointsTable(app, point)
            if app.numSelected ~= 1
                app.errShow('You need to select exactly 1 point for edit function!')
                app.EditButton.BackgroundColor = [.96 .96 .96]; % change the buttom back to its natural color
                return;
            end
            app.PointsTable.Data.Point(app.PointsTable.Data.S) = point;
            app.EditButton.BackgroundColor = [.96 .96 .96]; % change the buttom back to its natural color
        end
        
        function copyPointsTable(app, point)
            selectedPoints = app.PointsTable.Data.Point(app.PointsTable.Data.S);
            selectedPointsDistance = selectedPoints - min(selectedPoints);
            pointsToAdd = selectedPointsDistance + point;
            app.addToPointsTable(pointsToAdd, ...
                app.PointsTable.Data.Label(app.PointsTable.Data.S), app.PointsTable.Data.S(app.PointsTable.Data.S));
            
            % back to normal
            app.CopyButton.BackgroundColor = [.96 .96 .96]; % change the buttom back to its natural color
            app.mainPlotClickResponseFunction = @addToPointsTable;
        end
        
        function offsetPointsTable(app, point)
            if app.numSelected ~= 1
                app.errShow('You need to select exactly 1 point for offset function!')
                app.OffsetButton.BackgroundColor = [.96 .96 .96]; % change the buttom back to its natural color
                return;
            end
            diffrence = point - app.PointsTable.Data.Point(app.PointsTable.Data.S);
            if strcmp(app.OffsetTypeDropDown.Value, 'This Point Forward')
                selectedIndex = find(app.PointsTable.Data.S, true);
                app.PointsTable.Data.Point(selectedIndex:end) = ....
                    app.PointsTable.Data.Point(selectedIndex:end) + diffrence;
            else
                app.PointsTable.Data.Point = app.PointsTable.Data.Point + diffrence;
            end
            
            % back to normal
            app.mainPlotClickResponseFunction = @addToPointsTable;
            app.OffsetButton.BackgroundColor = [.96 .96 .96]; % change the buttom back to its natural color
        end
        
        function copyTemplatePoints(app, point)
            pointsToCopy = app.copiedTemplatePoints.Point;
            labelsToCopy = app.copiedTemplatePoints.Label;
            selectedPointsDistance = pointsToCopy - min(pointsToCopy);
            pointsToAdd = selectedPointsDistance + point;
            app.addToPointsTable(pointsToAdd, ...
                labelsToCopy);
        end
        
        function specto = spectogramOutlierSmoother(app, specto)
            avg = mean(specto');
            totalAvg = mean(avg);
            st = std(avg);
            i = 1;
            while totalAvg+4*st <= avg(i)
                i = i+1;
            end
            replacment = mean(specto(3:5,:))';
            for j = i-1:-1:1
                specto(j,:)=  replacment;
            end
        end
        
        function results = numSelected(app)
            results = sum(app.PointsTable.Data.S);
        end
        
        function errShow(app, message)
            msgbox(message);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initializeEnviroment;
        end

        % Callback function
        function RefreshButtonPushed(app, event)
            app.initializeEnviroment;
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            value = app.DataDropDown.Value;
            app.data = evalin('base',value);
            
            app.previewPlotDraw;
        end

        % Value changed function: LabelsDropDown
        function LabelsDropDownValueChanged(app, event)
            value = app.LabelsDropDown.Value;
            app.labels = evalin('base',value);
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            Fs =5e4;
            app.player = audioplayer(app.data(app.mainPlotStart:app.mainPlotEnd)...
                , Fs);
            
            spectoCursorHandle = line(app.SpectoPlot, [0, 0], [app.MainPlot.YLim(1), app.SpectoPlot.YLim(2)]...
                ,'color','b','Marker','*','MarkerEdgeColor','b','LineStyle','-','linewidth',2);
            mainCursorHandle = line(app.MainPlot, [0, 0], [app.MainPlot.YLim(1), app.MainPlot.YLim(2)]...
                ,'color','b','Marker','*','MarkerEdgeColor','b','LineStyle','-','linewidth',2);
            
            set(app.player,'TimerFcn',@ejecutar);
            app.player.play;
            
            function []=ejecutar(a, ~)
                percentage = a.CurrentSample/a.TotalSamples;
                
                xRelSpecto = app.SpectoPlot.XLim(2) * percentage;
                xRelMain = app.MainPlot.XLim(2) * percentage;
                set(spectoCursorHandle,'XData',[xRelSpecto xRelSpecto]);
                set(mainCursorHandle,'XData',[xRelMain xRelMain]);
                drawnow;
            end
        end

        % Cell edit callback: PointsTable
        function PointsTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.mainPlotDrawer;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.PointsTable.Data(app.PointsTable.Data.S,:) = [];
            app.mainPlotDrawer;
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            value = app.ImportDropDown.Value;
            app.PointsTable.Data = evalin('base',value);
            
            app.mainPlotDrawer;
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            app.mainPlotClickResponseFunction = @editPointsTable;
            app.EditButton.BackgroundColor = 'g';
        end

        % Button pushed function: OffsetButton
        function OffsetButtonPushed(app, event)
            app.mainPlotClickResponseFunction = @offsetPointsTable;
            app.OffsetButton.BackgroundColor = 'g';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file, path] = uiputfile({'*.mat'});
            fullPath = fullfile([path, file]);
            varToSave = app.PointsTable.Data;
            save(fullPath, 'varToSave');
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            app.mainPlotClickResponseFunction = @copyPointsTable;
            app.CopyButton.BackgroundColor = 'g';
        end

        % Key release function: LabelerUIFigure
        function LabelerUIFigureKeyRelease(app, event)
            key = event.Key;
            if key == 'del'
                app.PointsTable.Data(app.PointsTable.Data.S,:) = [];
                app.mainPlotDrawer;
            end
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.PointsTable.Data.S(1:end) = ...
                    true(height(app.PointsTable.Data),1);
        end

        % Button pushed function: UnSelectAllButton
        function UnSelectAllButtonPushed(app, event)
            app.PointsTable.Data.S(1:end) = ...
                    false(height(app.PointsTable.Data),1);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed2(app, event)
            app.initializeEnviroment;
        end

        % Callback function
        function ButtonValueChanged(app, event)
            value = app.TemplateModeButton.Value;
            
        end

        % Button pushed function: TemplateModeButton
        function TemplateModeButtonPushed(app, event)
            if ~isequal(app.mainPlotClickResponseFunction, @copyTemplatePoints)
                app.copiedTemplatePoints = app.PointsTable.Data(app.PointsTable.Data.S, :);
                
                app.mainPlotClickResponseFunction = @copyTemplatePoints;
                app.TemplateModeButton.BackgroundColor = 'g';
            else
                % back to normal
                app.mainPlotClickResponseFunction = @addToPointsTable;
                app.TemplateModeButton.BackgroundColor = [.96 .96 .96]; % change the buttom back to its natural color
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LabelerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {684, 684};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {285, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LabelerUIFigure = uifigure('Visible', 'off');
            app.LabelerUIFigure.Name = 'Labeler';
            app.LabelerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.LabelerUIFigure.KeyReleaseFcn = createCallbackFcn(app, @LabelerUIFigureKeyRelease, true);
            app.GridLayout = uigridlayout(app.LabelerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanelGrid = uigridlayout(app.LeftPanel);
            app.TabGroup = uitabgroup(app.LeftPanelGrid);
            app.ReadDataTab = uitab(app.TabGroup);
            app.ReadDataTab.Title = 'Read Data';
            app.GridLayout2 = uigridlayout(app.ReadDataTab);
            app.DataDropDown = uidropdown(app.GridLayout2);
            app.DataDropDown.Items = {'<No Var in Workspace>'};
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.DataDropDown.Value = '<No Var in Workspace>';
            app.LabelsDropDown = uidropdown(app.GridLayout2);
            app.LabelsDropDown.Items = {'<No Cat Vars in Workspace>'};
            app.LabelsDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelsDropDownValueChanged, true);
            app.LabelsDropDown.Value = '<No Cat Vars in Workspace>';
            app.RefreshButton = uibutton(app.GridLayout2, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed2, true);
            app.RefreshButton.Text = 'Refresh';
            app.SaveLoadTab = uitab(app.TabGroup);
            app.SaveLoadTab.Title = 'Save/Load';
            app.GridLayout6 = uigridlayout(app.SaveLoadTab);
            app.ImportDropDown = uidropdown(app.GridLayout6);
            app.ImportDropDown.Items = {'<No Table in the Workspace>'};
            app.ImportDropDown.Value = '<No Table in the Workspace>';
            app.ImportButton = uibutton(app.GridLayout6, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.OffsetButton = uibutton(app.GridLayout6, 'push');
            app.OffsetButton.ButtonPushedFcn = createCallbackFcn(app, @OffsetButtonPushed, true);
            app.OffsetButton.Text = 'Offset';
            app.OffsetTypeDropDown = uidropdown(app.GridLayout6);
            app.OffsetTypeDropDown.Items = {'This Point Forward', 'All of the Points'};
            app.OffsetTypeDropDown.Value = 'This Point Forward';
            app.SaveButton = uibutton(app.GridLayout6, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MiscFunctionsTab = uitab(app.TabGroup);
            app.MiscFunctionsTab.Title = 'Misc. Functions';
            app.GridLayout5 = uigridlayout(app.MiscFunctionsTab);
            app.PlayButton = uibutton(app.GridLayout5, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.SelectAllButton = uibutton(app.GridLayout7, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.UnSelectAllButton = uibutton(app.GridLayout7, 'push');
            app.UnSelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @UnSelectAllButtonPushed, true);
            app.UnSelectAllButton.Text = 'UnSelect All';
            app.TemplateModeButton = uibutton(app.GridLayout5, 'push');
            app.TemplateModeButton.ButtonPushedFcn = createCallbackFcn(app, @TemplateModeButtonPushed, true);
            app.TemplateModeButton.Text = 'Template Mode';
            app.PointsTable = uitable(app.LeftPanelGrid);
            app.PointsTable.ColumnName = {'Label'; 'Point'; 'S'};
            app.PointsTable.RowName = {};
            app.PointsTable.ColumnSortable = [true true false];
            app.PointsTable.ColumnEditable = true;
            app.PointsTable.CellEditCallback = createCallbackFcn(app, @PointsTableCellEdit, true);
            app.Buttons = uigridlayout(app.LeftPanelGrid);
            app.GridLayout3 = uigridlayout(app.Buttons);
            app.CursorSizeSlider = uislider(app.GridLayout3);
            app.CursorSizeSlider.Limits = [0.1 30];
            app.CursorSizeSlider.MajorTicks = [0.1 10 20 30 40 50 60 70 80 90 100];
            app.CursorSizeSlider.Value = 10;
            app.GridLayout4 = uigridlayout(app.Buttons);
            app.DeleteButton = uibutton(app.GridLayout4, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.EditButton = uibutton(app.GridLayout4, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.CopyButton = uibutton(app.GridLayout4, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            app.RightPanel = uipanel(app.GridLayout);
            app.labelingGrid = uigridlayout(app.RightPanel);
            app.MainPlot = uiaxes(app.labelingGrid);
            title(app.MainPlot, '')
            xlabel(app.MainPlot, '')
            ylabel(app.MainPlot, '')
            app.MainPlot.XTick = [];
            app.MainPlot.YTick = [];
            app.SpectoPlot = uiaxes(app.labelingGrid);
            title(app.SpectoPlot, '')
            xlabel(app.SpectoPlot, '')
            ylabel(app.SpectoPlot, '')
            app.SpectoPlot.XTick = [];
            app.SpectoPlot.YTick = [];
            app.PreviewPlot = uiaxes(app.labelingGrid);
            title(app.PreviewPlot, '')
            xlabel(app.PreviewPlot, '')
            ylabel(app.PreviewPlot, '')
            app.PreviewPlot.XTick = [];
            app.PreviewPlot.YTick = [];
            % Show the figure after all components are created
            app.LabelerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = labelMaster

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LabelerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LabelerUIFigure)
        end
    end
end