classdef labelerPro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        DataDropDownLabel    matlab.ui.control.Label
        DataDropDown         matlab.ui.control.DropDown
        CursorSizeLabel      matlab.ui.control.Label
        CursorSizeSlider     matlab.ui.control.Slider
        pointsTable          matlab.ui.control.Table
        LabelsDropDownLabel  matlab.ui.control.Label
        LabelsDropDown       matlab.ui.control.DropDown
        DeleteButton         matlab.ui.control.Button
        PlayButton           matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
        previewPlot          matlab.ui.control.UIAxes
        mainPlot             matlab.ui.control.UIAxes
        cursor               matlab.ui.control.Slider
        freqPlot             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data % plot values
        length % length of data
        previewPlotSkippingStep % skipping in plotprevie
        numPreviewPlotDataPoints = 1e6;
        mainPlotHandle
        previewPlotHandle
        previewLength
        mainPlotStart
        mainPlotEnd
        labels
        numLabels
    end
    
    properties (Access = public)
        originalTable = table(); % Description
        outTable = table();
        
        player
    end
    
    methods (Access = private)
        function previewPlotDraw(app)
            app.previewPlotSkippingStep = ceil(length(app.data)...
                /app.numPreviewPlotDataPoints);
            app.previewPlotHandle = plot(app.previewPlot,...
                app.data(1:app.previewPlotSkippingStep:end));
            app.previewLength = ...
                length(app.data(1:app.previewPlotSkippingStep:end));
            app.previewPlotHandle.ButtonDownFcn = @app.curserMove;
        end
        function curserMove(app, ~, ~)
            cp = get(app.previewPlot,'CurrentPoint');
            
            app.previewPlotDraw;
            
            windowSize = app.previewLength * ...
                app.CursorSizeSlider.Value / 100;
            previewLeftLimit = floor(cp(1,1) - windowSize/2);
            previewLeftLimit = max(1, previewLeftLimit);
            previewRightLimit = previewLeftLimit + windowSize;
            previewRightLimit = min(previewRightLimit, app.previewLength);
            
            xline(app.previewPlot, previewLeftLimit, '--black');
            xline(app.previewPlot, previewRightLimit, '--black');
            
            app.mainPlotDrawer(previewLeftLimit, previewRightLimit)
            app.labelDraw;
        end
        function mainPlotDrawer(app, previewLeft, previewRight)
            left = 1 + (previewLeft-1) * app.previewPlotSkippingStep;
            right = 1 + (previewRight-1) * app.previewPlotSkippingStep;
            left = floor(left);
            right = floor(right);
            app.mainPlotStart = left;
            app.mainPlotEnd = right;
            app.mainPlotHandle = plot(app.mainPlot, app.data(left:right));
            xlim(app.mainPlot, [0, right-left]);
            
%             sound(app.data(left:right), 50000);

            app.freqPlotDrawer;

            app.mainPlotHandle.ButtonDownFcn = @app.clickOnMainPlot;
        end
        function freqPlotDrawer(app)
            data = app.data(app.mainPlotStart:app.mainPlotEnd);
            windowLength = 1000;
            overlap = round(0.75 * windowLength);
            ffTLength = windowLength;
            
            imgOut = stft(data,'Window', hamming(windowLength,"periodic"), ... 
                'OverlapLength', overlap, 'FFTLength',ffTLength);
            
            numFeatures = windowLength/2 + 1;
            imgOut = abs(imgOut(numFeatures-1:end,:));
            imgOut = app.spectogramOutlierSmoother(imgOut);
            imgOut = 1./imgOut';
            imgOut = db(imgOut);
            
            mainPlotDim = app.mainPlot.InnerPosition;
            freqPlotDim = app.freqPlot.InnerPosition;
            freqPlotHandle = imshow(imgOut','Parent', app.freqPlot, ...
                'XData', [1 freqPlotDim(3)], ...
                'YData', [1 freqPlotDim(4)]);
            app.freqPlot.XLim = [0 freqPlotHandle.XData(2)];
            app.freqPlot.YLim = [0 freqPlotHandle.YData(2)];
        end
        
        function clickOnMainPlot(app, ~, ~)
            cp = get(app.mainPlot, 'CurrentPoint');
            thisPoint = app.mainPlotStart + cp(1,1);
            %             xline(app.mainPlot, cp(1,1),'-',"ss");
            %             xline(app.mainPlot, thisPoint,'-',"ss");
            
            app.addToPoints(thisPoint);
        end
        
        function addToPoints(app, thisPoint)
%             if ~isempty(app.outTable)
%                 app.outTable.O = strings(height(app.outTable), 1);
%             end
            
%             No = 1;
            Label = categorical({''}, cellstr(app.labels));
            Point = thisPoint;
            O = "X";
            S = false;
            thisTable = table(Label, Point, S);
%             app.originalTable = [app.originalTable; thisTable];
            app.pointsTable.Data = [app.pointsTable.Data; thisTable];
            
%             app.pointsTable.Data = app.originalTable;
            app.pointsTable.Data.S(end) = true;
            app.pointsTable.Data.S(1:end-1) = ...
                false(height(app.pointsTable.Data)-1,1);
            app.pointsTable.Data = sortrows(app.pointsTable.Data, 'Point');
            app.pointsTable.Data.Row = string(1:height(app.pointsTable.Data));
            
            app.labelDraw;
        end
        
        function labelDraw(app)
            if ~isempty(app.pointsTable.Data)
                allPoints = app.pointsTable.Data.Point;
                allLabels = string(app.pointsTable.Data.Label);
                isDraw = (allPoints < app.mainPlotEnd) & ...
                    (app.mainPlotStart < allPoints);
                toDrawPoints = allPoints(isDraw);
                toNoteLabel = allLabels(isDraw);
                
                for i = 1:length(toDrawPoints)
                    xline(app.mainPlot, toDrawPoints(i) - app.mainPlotStart...
                        ,'-',toNoteLabel(i), 'LabelHorizontalAlignment', 'left');
                end
            end
            
            app.exportVar(app.pointsTable.Data, 'points')
        end
        
        function exportVar(app, var, name)
            assignin('base',name,var)
        end
    end
    
    methods (Access = public)
        
        function specto = spectogramOutlierSmoother(app, specto)
            avg = mean(specto');
            totalAvg = mean(avg);
            st = std(avg);
            i = 1;
            while totalAvg+4*st <= avg(i)
                i = i+1;
            end
            replacment = mean(specto(3:5,:))';
            for j = i-1:-1:1
                specto(j,:)=  replacment;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            vars = evalin('base', 'whos');
            workspaceVars = cell(size(vars));
            j = 1;
            for i=1:length(vars)
                workspaceVars{i} = vars(i).name;
                if vars(i).class == "categorical"
                    workspaceCatVars{j} = vars(i).name;
                    j = j+1;
                end
            end
            workspaceVars = [ { '<Select The Var>' }; workspaceVars ];
            workspaceCatVars = [ { '<Select Cat Var>' }, workspaceCatVars ];
            app.DataDropDown.Items = workspaceVars;
            app.LabelsDropDown.Items = workspaceCatVars;
            
            app.pointsTable.Data = table();
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            value = app.DataDropDown.Value;
            app.data = evalin('base',value);
            app.length = length(app.data);
            
            app.previewPlotDraw;
        end

        % Value changed function: LabelsDropDown
        function LabelsDropDownValueChanged(app, event)
            value = app.LabelsDropDown.Value;
            app.labels = evalin('base',value);
            app.numLabels = length(app.data);
            
            app.pointsTable.Enable = 'on';
        end

        % Cell edit callback: pointsTable
        function pointsTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.labelDraw;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.pointsTable.Data(app.pointsTable.Data.S,:) = [];
            app.labelDraw;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            Fs =5e4;
            app.player = audioplayer(app.data(app.mainPlotStart:app.mainPlotEnd)...
                , Fs);
%             app.player.TimerPeriod = 0.01;

            set(app.player,'TimerFcn',@ejecutar);
            app.player.play;
            
            function []=ejecutar(a, b)
                app.cursor.Value = a.CurrentSample/a.TotalSamples;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {490, 490};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {189, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataDropDownLabel = uilabel(app.LeftPanel);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.LeftPanel);
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.CursorSizeLabel = uilabel(app.LeftPanel);
            app.CursorSizeLabel.Text = 'Cursor Size (%)';
            app.CursorSizeSlider = uislider(app.LeftPanel);
            app.CursorSizeSlider.Limits = [0.1 50.1];
            app.CursorSizeSlider.MajorTicks = [0.1 10 20 30 40 50];
            app.CursorSizeSlider.Value = 20;
            app.pointsTable = uitable(app.LeftPanel);
            app.pointsTable.ColumnName = {'Label'; 'Point'; 'S'};
            app.pointsTable.RowName = {};
            app.pointsTable.ColumnSortable = [true true false];
            app.pointsTable.ColumnEditable = true;
            app.pointsTable.CellEditCallback = createCallbackFcn(app, @pointsTableCellEdit, true);
            app.LabelsDropDownLabel = uilabel(app.LeftPanel);
            app.LabelsDropDownLabel.Text = 'Labels';
            app.LabelsDropDown = uidropdown(app.LeftPanel);
            app.LabelsDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelsDropDownValueChanged, true);
            app.DeleteButton = uibutton(app.LeftPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.PlayButton = uibutton(app.LeftPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.RightPanel = uipanel(app.GridLayout);
            app.previewPlot = uiaxes(app.RightPanel);
            title(app.previewPlot, '')
            xlabel(app.previewPlot, '')
            ylabel(app.previewPlot, '')
            app.previewPlot.XTick = [];
            app.previewPlot.XTickLabel = '';
            app.previewPlot.YTick = [];
            app.mainPlot = uiaxes(app.RightPanel);
            title(app.mainPlot, '')
            xlabel(app.mainPlot, '')
            ylabel(app.mainPlot, '')
            app.mainPlot.YTick = [];
            app.cursor = uislider(app.RightPanel);
            app.cursor.Limits = [0 1];
            app.cursor.MajorTicks = [];
            app.cursor.MinorTicks = [];
            app.freqPlot = uiaxes(app.RightPanel);
            title(app.freqPlot, '')
            xlabel(app.freqPlot, '')
            ylabel(app.freqPlot, '')
            app.freqPlot.XTick = [];
            app.freqPlot.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = labelerPro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end