classdef variableSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        VarsDropDown  matlab.ui.control.DropDown
        Label         matlab.ui.control.Label
        OkButton      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, text, type)
            vars = evalin('base', 'whos');
            workspaceTypreVars = cell(0);
            workspaceVars = cell(size(vars));
            j = 1;
            for i=1:length(vars)
                workspaceVars{i} = vars(i).name;
                if vars(i).class == type
                    workspaceTypreVars{j} = vars(i).name;
                    j = j+1;
                end
            end
            workspaceTypreVars = [ { '<Select The Var>' }, workspaceTypreVars ];
            app.VarsDropDown.Items = workspaceTypreVars;
            app.Label.Text = strcat(text, ':');
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VarsDropDown = uidropdown(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = variableSelector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end